classdef RoboespasDefinitivo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartPanel                      matlab.ui.container.Panel
        NewPatientButton                matlab.ui.control.Button
        LoadPatientButton               matlab.ui.control.Button
        PatientDataPanel                matlab.ui.container.Panel
        PatientDataLabel                matlab.ui.control.Label
        LeftArmLabel                    matlab.ui.control.Label
        RightArmLabel                   matlab.ui.control.Label
        SavePatientButton               matlab.ui.control.Button
        IDEditFieldLabel                matlab.ui.control.Label
        IDEditField                     matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        WeightEditFieldLabel            matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        LeftWristElbowEditFieldLabel    matlab.ui.control.Label
        LeftWristElbowEditField         matlab.ui.control.NumericEditField
        LeftElbowShoulderEditFieldLabel  matlab.ui.control.Label
        LeftElbowShoulderEditField      matlab.ui.control.NumericEditField
        LeftShoulderBreastboneEditFieldLabel  matlab.ui.control.Label
        LeftShoulderBreastboneEditField  matlab.ui.control.NumericEditField
        LeftWristHandleEditFieldLabel   matlab.ui.control.Label
        LeftWristHandleEditField        matlab.ui.control.NumericEditField
        RightWristHandleEditFieldLabel  matlab.ui.control.Label
        RightWristHandleEditField       matlab.ui.control.NumericEditField
        RightWristElbowEditFieldLabel   matlab.ui.control.Label
        RightWristElbowEditField        matlab.ui.control.NumericEditField
        RightElbowShoulderEditFieldLabel  matlab.ui.control.Label
        RightElbowShoulderEditField     matlab.ui.control.NumericEditField
        RightShoulderBreastboneEditFieldLabel  matlab.ui.control.Label
        RightShoulderBreastboneEditField  matlab.ui.control.NumericEditField
        GenderDropDownLabel             matlab.ui.control.Label
        GenderDropDown                  matlab.ui.control.DropDown
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        BackToStartPatientDataButton    matlab.ui.control.Button
        SurnamesEditFieldLabel          matlab.ui.control.Label
        SurnamesEditField               matlab.ui.control.EditField
        SpasticityLevelEditFieldLabel   matlab.ui.control.Label
        SpasticityLevelEditField        matlab.ui.control.NumericEditField
        BackToMenuPatientDataButton     matlab.ui.control.Button
        LoadPatientPanel                matlab.ui.container.Panel
        LoadPatientTable                matlab.ui.control.Table
        BackToStartLoadPatientButton    matlab.ui.control.Button
        ChoosePatientButton             matlab.ui.control.Button
        PatientMenuPanel                matlab.ui.container.Panel
        EditPatientButton               matlab.ui.control.Button
        StartTrialButton                matlab.ui.control.Button
        ShowMedicalHistoryButton        matlab.ui.control.Button
        PatientMenuEditFieldLabel       matlab.ui.control.Label
        PatientMenuEditField            matlab.ui.control.NumericEditField
        BackToStartPatientMenuButton    matlab.ui.control.Button
        MedicalHistoryPanel             matlab.ui.container.Panel
        MedicalHistoryTree              matlab.ui.container.Tree
        PersonalDataPanel               matlab.ui.container.Panel
        LeftArmLabel2                   matlab.ui.control.Label
        RightArmLabel2                  matlab.ui.control.Label
        IDEditFieldLabel2               matlab.ui.control.Label
        IDEditField2                    matlab.ui.control.NumericEditField
        SpasticityLevelEditFieldLabel2  matlab.ui.control.Label
        SpasticityLevelEditField2       matlab.ui.control.NumericEditField
        HeightEditFieldLabel2           matlab.ui.control.Label
        HeightEditField2                matlab.ui.control.NumericEditField
        WeightEditFieldLabel2           matlab.ui.control.Label
        WeightEditField2                matlab.ui.control.NumericEditField
        AgeEditFieldLabel2              matlab.ui.control.Label
        AgeEditField2                   matlab.ui.control.NumericEditField
        LeftWristElbowEditFieldLabel2   matlab.ui.control.Label
        LeftWristElbowEditField2        matlab.ui.control.NumericEditField
        LeftElbowShoulderEditFieldLabel2  matlab.ui.control.Label
        LeftElbowShoulderEditField2     matlab.ui.control.NumericEditField
        LeftShoulderBreastboneEditFieldLabel2  matlab.ui.control.Label
        LeftShoulderBreastboneEditField2  matlab.ui.control.NumericEditField
        LeftWristHandleEditFieldLabel2  matlab.ui.control.Label
        LeftWristHandleEditField2       matlab.ui.control.NumericEditField
        RightWristHandleEditFieldLabel2  matlab.ui.control.Label
        RightWristHandleEditField2      matlab.ui.control.NumericEditField
        RightWristElbowEditFieldLabel2  matlab.ui.control.Label
        RightWristElbowEditField2       matlab.ui.control.NumericEditField
        RightElbowShoulderEditFieldLabel2  matlab.ui.control.Label
        RightElbowShoulderEditField2    matlab.ui.control.NumericEditField
        RightShoulderBreastboneEditFieldLabel2  matlab.ui.control.Label
        RightShoulderBreastboneEditField2  matlab.ui.control.NumericEditField
        NameEditFieldLabel2             matlab.ui.control.Label
        NameEditField2                  matlab.ui.control.EditField
        SurnamesEditFieldLabel2         matlab.ui.control.Label
        SurnamesEditField2              matlab.ui.control.EditField
        GenderEditFieldLabel2           matlab.ui.control.Label
        GenderEditField2                matlab.ui.control.EditField
        BackToMenuMedicalHistoryButton  matlab.ui.control.Button
        ResultsButtonsPanel             matlab.ui.container.Panel
        TrajectoryLabel                 matlab.ui.control.Label
        JointLabel                      matlab.ui.control.Label
        qButton                         matlab.ui.control.Button
        dqButton                        matlab.ui.control.Button
        EqButton                        matlab.ui.control.Button
        EdqButton                       matlab.ui.control.Button
        CartesianLabel                  matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        ExButton                        matlab.ui.control.Button
        EdxButton                       matlab.ui.control.Button
        ThreeDButton                    matlab.ui.control.Button
        dxButton                        matlab.ui.control.Button
        ForceSensorLabel                matlab.ui.control.Label
        FTButton                        matlab.ui.control.Button
        DelsysSensorsLabel              matlab.ui.control.Label
        EMGButton                       matlab.ui.control.Button
        AccelerationsButton             matlab.ui.control.Button
        AngularVelocityButton           matlab.ui.control.Button
        ddqButton                       matlab.ui.control.Button
        EddqButton                      matlab.ui.control.Button
        EpqButton                       matlab.ui.control.Button
        EpdqButton                      matlab.ui.control.Button
        EpddqButton                     matlab.ui.control.Button
        EpxButton                       matlab.ui.control.Button
        EpdxButton                      matlab.ui.control.Button
        JointPrecisionLabel             matlab.ui.control.Label
        JointAccuracyLabel              matlab.ui.control.Label
        CartesianPrecisionLabel         matlab.ui.control.Label
        CartesianAccuracyLabel          matlab.ui.control.Label
        TrialDataPanel                  matlab.ui.container.Panel
        TrialDataPatientEditField       matlab.ui.control.EditField
        TrialDataChoosedArmEditField    matlab.ui.control.EditField
        TrialDataTrajectoryDateCapturedEditField  matlab.ui.control.EditField
        TrialDataTrajectoryDateExecutedEditField  matlab.ui.control.EditField
        PlotsPanel                      matlab.ui.container.Panel
        CloseImage                      matlab.ui.control.Image
    end

    
    properties(Constant)
        
        PatientsDatabasesFilePath = [fileparts(which(mfilename)),'\patientsDB.mat'];
        NamesIDDatabasesFilePath = [fileparts(which(mfilename)),'\namesid.mat'];
        TrialsDatabasesFilePath = [fileparts(which(mfilename)),'\trials.mat'];
       
        ArmReference = ['L', 'R']; % Used to relate L and R with 1 and 2 numbers, possible values of ArmSwitch
        
        DelsysSensorsConnected = 0; %Description
        FTSensorConnected = 0; %Description
        ROSConnected = 0;
        
        TimeWaitBetweenRepetitions = 6 %TODO: Add option to change?
        TrajectorySelectStr = {'Seleccione una trayectoria'};
        MovementSelectStr = {'Seleccione un movimiento'};
        
        MovementMap = containers.Map({'Flexión-Extensión Codo'}, {'flexext'});
        MaxSensors = 6;
        
    end
    
    properties (Access = private)
        
        IIWAControlObject
        FTSensorObject
        DelsysSensorsObject
        IDPatient
        Month
        
        ExecutionTimes = {};
        TrialsLoad;
        
    end
    
    methods (Access = private)
        
        function ResizeApp(app)
            try
            
                %StartPanel
                
                app.StartPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.NewPatientButton.Position(3)=app.StartPanel.Position(3)*(1/4);
                app.NewPatientButton.Position(4)=app.NewPatientButton.Position(3)*(0.16);
                app.NewPatientButton.Position(1)=app.StartPanel.Position(3)*(1/2)-app.NewPatientButton.Position(3)*(1/2);
                app.NewPatientButton.Position(2)=app.StartPanel.Position(4)*(6/10);
                app.NewPatientButton.FontSize=app.NewPatientButton.Position(4)-app.NewPatientButton.Position(4)*0.45;
                
                app.LoadPatientButton.Position(3)=app.StartPanel.Position(3)*(1/4);
                app.LoadPatientButton.Position(4)=app.LoadPatientButton.Position(3)*(0.16);
                app.LoadPatientButton.Position(1)=app.StartPanel.Position(3)*(1/2)-app.LoadPatientButton.Position(3)*(1/2);
                app.LoadPatientButton.Position(2)=app.StartPanel.Position(4)*(4/10);
                app.LoadPatientButton.FontSize=app.LoadPatientButton.Position(4)-app.LoadPatientButton.Position(4)*0.45;
                
                %PatientDataPanel
                
                app.PatientDataPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.PatientDataLabel.Position(3)=app.PatientDataPanel.Position(3)*(1/3);
                app.PatientDataLabel.Position(4)=app.PatientDataLabel.Position(3)*(0.12);
                app.PatientDataLabel.Position(1)=app.PatientDataPanel.Position(3)*(1/2)-app.PatientDataLabel.Position(3)*(1/2);
                app.PatientDataLabel.Position(2)=app.PatientDataPanel.Position(4)*(25/28);
                app.PatientDataLabel.FontSize=app.PatientDataLabel.Position(4)-app.PatientDataLabel.Position(4)*0.45;
                
                app.NameEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.NameEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.NameEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/5)-app.NameEditField.Position(3)*(1/2);
                app.NameEditField.Position(2)=app.PatientDataPanel.Position(4)*(11/14);
                app.NameEditField.FontSize=app.NameEditField.Position(4)-app.NameEditField.Position(4)*0.45;
                app.NameEditFieldLabel.Position(3)=app.NameEditField.Position(3);
                app.NameEditFieldLabel.Position(4)=app.NameEditField.Position(4);
                app.NameEditFieldLabel.Position(1)=app.NameEditField.Position(1)-app.NameEditFieldLabel.Position(3)-app.NameEditField.Position(3)*(1/10);
                app.NameEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(11/14);
                app.NameEditFieldLabel.FontSize=app.NameEditField.FontSize;
                
                app.SurnamesEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.SurnamesEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.SurnamesEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/2)-app.SurnamesEditField.Position(3)*(1/2);
                app.SurnamesEditField.Position(2)=app.PatientDataPanel.Position(4)*(11/14);
                app.SurnamesEditField.FontSize=app.SurnamesEditField.Position(4)-app.SurnamesEditField.Position(4)*0.45;
                app.SurnamesEditFieldLabel.Position(3)=app.SurnamesEditField.Position(3);
                app.SurnamesEditFieldLabel.Position(4)=app.SurnamesEditField.Position(4);
                app.SurnamesEditFieldLabel.Position(1)=app.SurnamesEditField.Position(1)-app.SurnamesEditFieldLabel.Position(3)-app.SurnamesEditField.Position(3)*(1/10);
                app.SurnamesEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(11/14);
                app.SurnamesEditFieldLabel.FontSize=app.SurnamesEditField.FontSize;
                
                app.IDEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.IDEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.IDEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/5)-app.IDEditField.Position(3)*(1/2);
                app.IDEditField.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.IDEditField.FontSize=app.IDEditField.Position(4)-app.IDEditField.Position(4)*0.45;
                app.IDEditFieldLabel.Position(3)=app.IDEditField.Position(3);
                app.IDEditFieldLabel.Position(4)=app.IDEditField.Position(4);
                app.IDEditFieldLabel.Position(1)=app.IDEditField.Position(1)-app.IDEditFieldLabel.Position(3)-app.IDEditField.Position(3)*(1/10);
                app.IDEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.IDEditFieldLabel.FontSize=app.IDEditField.FontSize;
                
                app.SpasticityLevelEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.SpasticityLevelEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.SpasticityLevelEditField.Position(1)=app.PatientDataPanel.Position(3)*(2/4)-app.SpasticityLevelEditField.Position(3)*(1/2);
                app.SpasticityLevelEditField.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.SpasticityLevelEditField.FontSize=app.SpasticityLevelEditField.Position(4)-app.SpasticityLevelEditField.Position(4)*0.45;
                app.SpasticityLevelEditFieldLabel.Position(3)=app.SpasticityLevelEditField.Position(3)*2;
                app.SpasticityLevelEditFieldLabel.Position(4)=app.SpasticityLevelEditField.Position(4);
                app.SpasticityLevelEditFieldLabel.Position(1)=app.SpasticityLevelEditField.Position(1)-app.SpasticityLevelEditFieldLabel.Position(3)-app.SpasticityLevelEditField.Position(3)*(1/10);
                app.SpasticityLevelEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.SpasticityLevelEditFieldLabel.FontSize=app.SpasticityLevelEditField.FontSize;
                
                app.AgeEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.AgeEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.AgeEditField.Position(1)=app.PatientDataPanel.Position(3)*(3/4)-app.AgeEditField.Position(3)*(1/2);
                app.AgeEditField.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.AgeEditField.FontSize=app.AgeEditField.Position(4)-app.AgeEditField.Position(4)*0.45;
                app.AgeEditFieldLabel.Position(3)=app.AgeEditField.Position(3);
                app.AgeEditFieldLabel.Position(4)=app.AgeEditField.Position(4);
                app.AgeEditFieldLabel.Position(1)=app.AgeEditField.Position(1)-app.AgeEditFieldLabel.Position(3)-app.AgeEditField.Position(3)*(1/10);
                app.AgeEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.AgeEditFieldLabel.FontSize=app.AgeEditField.FontSize;
                
                app.HeightEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.HeightEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.HeightEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/5)-app.HeightEditField.Position(3)*(1/2);
                app.HeightEditField.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.HeightEditField.FontSize=app.HeightEditField.Position(4)-app.HeightEditField.Position(4)*0.45;
                app.HeightEditFieldLabel.Position(3)=app.HeightEditField.Position(3);
                app.HeightEditFieldLabel.Position(4)=app.HeightEditField.Position(4);
                app.HeightEditFieldLabel.Position(1)=app.HeightEditField.Position(1)-app.HeightEditFieldLabel.Position(3)-app.HeightEditField.Position(3)*(1/10);
                app.HeightEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.HeightEditFieldLabel.FontSize=app.HeightEditField.FontSize;
                
                app.WeightEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.WeightEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.WeightEditField.Position(1)=app.PatientDataPanel.Position(3)*(2/4)-app.WeightEditField.Position(3)*(1/2);
                app.WeightEditField.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.WeightEditField.FontSize=app.WeightEditField.Position(4)-app.WeightEditField.Position(4)*0.45;
                app.WeightEditFieldLabel.Position(3)=app.WeightEditField.Position(3);
                app.WeightEditFieldLabel.Position(4)=app.WeightEditField.Position(4);
                app.WeightEditFieldLabel.Position(1)=app.WeightEditField.Position(1)-app.WeightEditFieldLabel.Position(3)-app.WeightEditField.Position(3)*(1/10);
                app.WeightEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.WeightEditFieldLabel.FontSize=app.WeightEditField.FontSize;
                
                app.GenderDropDown.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.GenderDropDown.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.GenderDropDown.Position(1)=app.PatientDataPanel.Position(3)*(3/4)-app.GenderDropDown.Position(3)*(1/2);
                app.GenderDropDown.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.GenderDropDown.FontSize=app.GenderDropDown.Position(4)-app.GenderDropDown.Position(4)*0.45;
                app.GenderDropDownLabel.Position(3)=app.GenderDropDown.Position(3);
                app.GenderDropDownLabel.Position(4)=app.GenderDropDown.Position(4);
                app.GenderDropDownLabel.Position(1)=app.GenderDropDown.Position(1)-app.GenderDropDownLabel.Position(3)-app.GenderDropDown.Position(3)*(1/10);
                app.GenderDropDownLabel.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.GenderDropDownLabel.FontSize=app.GenderDropDown.FontSize;
                
                app.LeftArmLabel.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftArmLabel.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftArmLabel.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftArmLabel.Position(3)*(1/2);
                app.LeftArmLabel.Position(2)=app.PatientDataPanel.Position(4)*(7/14);
                app.LeftArmLabel.FontSize=app.LeftArmLabel.Position(4)-app.LeftArmLabel.Position(4)*0.45;
                
                app.RightArmLabel.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightArmLabel.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightArmLabel.Position(1)=app.PatientDataPanel.Position(3)*(2/3)-app.RightArmLabel.Position(3)*(1/2);
                app.RightArmLabel.Position(2)=app.PatientDataPanel.Position(4)*(7/14);
                app.RightArmLabel.FontSize=app.RightArmLabel.Position(4)-app.RightArmLabel.Position(4)*0.45;
                
                app.LeftWristElbowEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftWristElbowEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftWristElbowEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftWristElbowEditField.Position(3)*(1/2);
                app.LeftWristElbowEditField.Position(2)=app.PatientDataPanel.Position(4)*(6/14);
                app.LeftWristElbowEditField.FontSize=app.LeftWristElbowEditField.Position(4)-app.LeftWristElbowEditField.Position(4)*0.45;
                app.LeftWristElbowEditFieldLabel.Position(3)=app.LeftWristElbowEditField.Position(3);
                app.LeftWristElbowEditFieldLabel.Position(4)=app.LeftWristElbowEditField.Position(4);
                app.LeftWristElbowEditFieldLabel.Position(1)=app.LeftWristElbowEditField.Position(1)-app.LeftWristElbowEditFieldLabel.Position(3)-app.LeftWristElbowEditField.Position(3)*(1/10);
                app.LeftWristElbowEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(6/14);
                app.LeftWristElbowEditFieldLabel.FontSize=app.LeftWristElbowEditField.FontSize;
                
                app.RightWristElbowEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightWristElbowEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightWristElbowEditField.Position(1)=app.PatientDataPanel.Position(3)*(2/3)-app.RightWristElbowEditField.Position(3)*(1/2);
                app.RightWristElbowEditField.Position(2)=app.PatientDataPanel.Position(4)*(6/14);
                app.RightWristElbowEditField.FontSize=app.RightWristElbowEditField.Position(4)-app.RightWristElbowEditField.Position(4)*0.45;
                app.RightWristElbowEditFieldLabel.Position(3)=app.RightWristElbowEditField.Position(3);
                app.RightWristElbowEditFieldLabel.Position(4)=app.RightWristElbowEditField.Position(4);
                app.RightWristElbowEditFieldLabel.Position(1)=app.RightWristElbowEditField.Position(1)-app.RightWristElbowEditFieldLabel.Position(3)-app.RightWristElbowEditField.Position(3)*(1/10);
                app.RightWristElbowEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(6/14);
                app.RightWristElbowEditFieldLabel.FontSize=app.RightWristElbowEditField.FontSize;
                
                app.LeftElbowShoulderEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftElbowShoulderEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftElbowShoulderEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftElbowShoulderEditField.Position(3)*(1/2);
                app.LeftElbowShoulderEditField.Position(2)=app.PatientDataPanel.Position(4)*(5/14);
                app.LeftElbowShoulderEditField.FontSize=app.LeftElbowShoulderEditField.Position(4)-app.LeftElbowShoulderEditField.Position(4)*0.45;
                app.LeftElbowShoulderEditFieldLabel.Position(3)=app.LeftElbowShoulderEditField.Position(3);
                app.LeftElbowShoulderEditFieldLabel.Position(4)=app.LeftElbowShoulderEditField.Position(4);
                app.LeftElbowShoulderEditFieldLabel.Position(1)=app.LeftElbowShoulderEditField.Position(1)-app.LeftElbowShoulderEditFieldLabel.Position(3)-app.LeftElbowShoulderEditField.Position(3)*(1/10);
                app.LeftElbowShoulderEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(5/14);
                app.LeftElbowShoulderEditFieldLabel.FontSize=app.LeftElbowShoulderEditField.FontSize;
                
                app.RightElbowShoulderEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightElbowShoulderEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightElbowShoulderEditField.Position(1)=app.PatientDataPanel.Position(3)*(2/3)-app.RightElbowShoulderEditField.Position(3)*(1/2);
                app.RightElbowShoulderEditField.Position(2)=app.PatientDataPanel.Position(4)*(5/14);
                app.RightElbowShoulderEditField.FontSize=app.RightElbowShoulderEditField.Position(4)-app.RightElbowShoulderEditField.Position(4)*0.45;
                app.RightElbowShoulderEditFieldLabel.Position(3)=app.RightElbowShoulderEditField.Position(3);
                app.RightElbowShoulderEditFieldLabel.Position(4)=app.RightElbowShoulderEditField.Position(4);
                app.RightElbowShoulderEditFieldLabel.Position(1)=app.RightElbowShoulderEditField.Position(1)-app.RightElbowShoulderEditFieldLabel.Position(3)-app.RightElbowShoulderEditField.Position(3)*(1/10);
                app.RightElbowShoulderEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(5/14);
                app.RightElbowShoulderEditFieldLabel.FontSize=app.RightElbowShoulderEditField.FontSize;
                
                app.LeftShoulderBreastboneEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftShoulderBreastboneEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftShoulderBreastboneEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftShoulderBreastboneEditField.Position(3)*(1/2);
                app.LeftShoulderBreastboneEditField.Position(2)=app.PatientDataPanel.Position(4)*(4/14);
                app.LeftShoulderBreastboneEditField.FontSize=app.LeftShoulderBreastboneEditField.Position(4)-app.LeftShoulderBreastboneEditField.Position(4)*0.45;
                app.LeftShoulderBreastboneEditFieldLabel.Position(3)=app.LeftShoulderBreastboneEditField.Position(3);
                app.LeftShoulderBreastboneEditFieldLabel.Position(4)=app.LeftShoulderBreastboneEditField.Position(4);
                app.LeftShoulderBreastboneEditFieldLabel.Position(1)=app.LeftShoulderBreastboneEditField.Position(1)-app.LeftShoulderBreastboneEditFieldLabel.Position(3)-app.LeftShoulderBreastboneEditField.Position(3)*(1/10);
                app.LeftShoulderBreastboneEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(4/14);
                app.LeftShoulderBreastboneEditFieldLabel.FontSize=app.LeftShoulderBreastboneEditField.FontSize;
                
                app.RightShoulderBreastboneEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightShoulderBreastboneEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightShoulderBreastboneEditField.Position(1)=app.PatientDataPanel.Position(3)*(2/3)-app.RightShoulderBreastboneEditField.Position(3)*(1/2);
                app.RightShoulderBreastboneEditField.Position(2)=app.PatientDataPanel.Position(4)*(4/14);
                app.RightShoulderBreastboneEditField.FontSize=app.RightShoulderBreastboneEditField.Position(4)-app.RightShoulderBreastboneEditField.Position(4)*0.45;
                app.RightShoulderBreastboneEditFieldLabel.Position(3)=app.RightShoulderBreastboneEditField.Position(3);
                app.RightShoulderBreastboneEditFieldLabel.Position(4)=app.RightShoulderBreastboneEditField.Position(4);
                app.RightShoulderBreastboneEditFieldLabel.Position(1)=app.RightShoulderBreastboneEditField.Position(1)-app.RightShoulderBreastboneEditFieldLabel.Position(3)-app.RightShoulderBreastboneEditField.Position(3)*(1/10);
                app.RightShoulderBreastboneEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(4/14);
                app.RightShoulderBreastboneEditFieldLabel.FontSize=app.RightShoulderBreastboneEditField.FontSize;
                
                app.LeftWristHandleEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftWristHandleEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftWristHandleEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftWristHandleEditField.Position(3)*(1/2);
                app.LeftWristHandleEditField.Position(2)=app.PatientDataPanel.Position(4)*(3/14);
                app.LeftWristHandleEditField.FontSize=app.LeftWristHandleEditField.Position(4)-app.LeftWristHandleEditField.Position(4)*0.45;
                app.LeftWristHandleEditFieldLabel.Position(3)=app.LeftWristHandleEditField.Position(3);
                app.LeftWristHandleEditFieldLabel.Position(4)=app.LeftWristHandleEditField.Position(4);
                app.LeftWristHandleEditFieldLabel.Position(1)=app.LeftWristHandleEditField.Position(1)-app.LeftWristHandleEditFieldLabel.Position(3)-app.LeftWristHandleEditField.Position(3)*(1/10);
                app.LeftWristHandleEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(3/14);
                app.LeftWristHandleEditFieldLabel.FontSize=app.LeftWristHandleEditField.FontSize;
                
                app.RightWristHandleEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightWristHandleEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightWristHandleEditField.Position(1)=app.PatientDataPanel.Position(3)*(2/3)-app.RightWristHandleEditField.Position(3)*(1/2);
                app.RightWristHandleEditField.Position(2)=app.PatientDataPanel.Position(4)*(3/14);
                app.RightWristHandleEditField.FontSize=app.RightWristHandleEditField.Position(4)-app.RightWristHandleEditField.Position(4)*0.45;
                app.RightWristHandleEditFieldLabel.Position(3)=app.RightWristHandleEditField.Position(3);
                app.RightWristHandleEditFieldLabel.Position(4)=app.RightWristHandleEditField.Position(4);
                app.RightWristHandleEditFieldLabel.Position(1)=app.RightWristHandleEditField.Position(1)-app.RightWristHandleEditFieldLabel.Position(3)-app.RightWristHandleEditField.Position(3)*(1/10);
                app.RightWristHandleEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(3/14);
                app.RightWristHandleEditFieldLabel.FontSize=app.RightWristHandleEditField.FontSize;
                
                app.BackToStartPatientDataButton.Position(3)=app.PatientDataPanel.Position(3)*(1/9);
                app.BackToStartPatientDataButton.Position(4)=app.BackToStartPatientDataButton.Position(3)*(0.19);
                app.BackToStartPatientDataButton.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.BackToStartPatientDataButton.Position(3)*(1/2);
                app.BackToStartPatientDataButton.Position(2)=app.PatientDataPanel.Position(4)*(1/14);
                app.BackToStartPatientDataButton.FontSize=app.BackToStartPatientDataButton.Position(4)-app.BackToStartPatientDataButton.Position(4)*0.45;
                
                app.BackToMenuPatientDataButton.Position(3)=app.PatientDataPanel.Position(3)*(1/9);
                app.BackToMenuPatientDataButton.Position(4)=app.BackToMenuPatientDataButton.Position(3)*(0.19);
                app.BackToMenuPatientDataButton.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.BackToMenuPatientDataButton.Position(3)*(1/2);
                app.BackToMenuPatientDataButton.Position(2)=app.PatientDataPanel.Position(4)*(1/14);
                app.BackToMenuPatientDataButton.FontSize=app.BackToMenuPatientDataButton.Position(4)-app.BackToMenuPatientDataButton.Position(4)*0.45;
                
                app.SavePatientButton.Position(3)=app.PatientDataPanel.Position(3)*(1/9);
                app.SavePatientButton.Position(4)=app.SavePatientButton.Position(3)*(0.19);
                app.SavePatientButton.Position(1)=app.PatientDataPanel.Position(3)*(2/3)-app.SavePatientButton.Position(3)*(1/2);
                app.SavePatientButton.Position(2)=app.PatientDataPanel.Position(4)*(1/14);
                app.SavePatientButton.FontSize=app.SavePatientButton.Position(4)-app.SavePatientButton.Position(4)*0.45;
                
                %LoadPatientPanel
                
                app.LoadPatientPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.LoadPatientTable.Position(1)=app.LoadPatientPanel.Position(3)*(1/10);
                app.LoadPatientTable.Position(2)=app.LoadPatientPanel.Position(4)*(3/14);
                app.LoadPatientTable.Position(3)=app.LoadPatientPanel.Position(3)*(8/10);
                app.LoadPatientTable.Position(4)=app.LoadPatientPanel.Position(4)*(10/14);
                app.LoadPatientTable.FontSize=app.LoadPatientTable.Position(4)*(0.03);
                
                app.BackToStartLoadPatientButton.Position(3)=app.LoadPatientPanel.Position(3)*(1/9);
                app.BackToStartLoadPatientButton.Position(4)=app.BackToStartLoadPatientButton.Position(3)*(0.17);
                app.BackToStartLoadPatientButton.Position(1)=app.LoadPatientPanel.Position(3)*(1/3)-app.BackToStartLoadPatientButton.Position(3)*(1/2);
                app.BackToStartLoadPatientButton.Position(2)=app.LoadPatientPanel.Position(4)*(1/14);
                app.BackToStartLoadPatientButton.FontSize=app.BackToStartLoadPatientButton.Position(4)-app.BackToStartLoadPatientButton.Position(4)*0.45;
                
                app.ChoosePatientButton.Position(3)=app.LoadPatientPanel.Position(3)*(1/9);
                app.ChoosePatientButton.Position(4)=app.ChoosePatientButton.Position(3)*(0.17);
                app.ChoosePatientButton.Position(1)=app.LoadPatientPanel.Position(3)*(2/3)-app.ChoosePatientButton.Position(3)*(1/2);
                app.ChoosePatientButton.Position(2)=app.LoadPatientPanel.Position(4)*(1/14);
                app.ChoosePatientButton.FontSize=app.ChoosePatientButton.Position(4)-app.ChoosePatientButton.Position(4)*0.45;
                
                %PatientMenuPanel
                
                app.PatientMenuPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.PatientMenuEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.PatientMenuEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.PatientMenuEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/5)-app.PatientMenuEditField.Position(3)*(1/2);
                app.PatientMenuEditField.Position(2)=app.PatientDataPanel.Position(4)*(13/14);
                app.PatientMenuEditField.FontSize=app.PatientMenuEditField.Position(4)-app.PatientMenuEditField.Position(4)*0.45;
                app.PatientMenuEditFieldLabel.Position(3)=app.PatientMenuEditField.Position(3);
                app.PatientMenuEditFieldLabel.Position(4)=app.PatientMenuEditField.Position(4);
                app.PatientMenuEditFieldLabel.Position(1)=app.PatientMenuEditField.Position(1)-app.PatientMenuEditFieldLabel.Position(3)-app.PatientMenuEditField.Position(3)*(1/10);
                app.PatientMenuEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(13/14);
                app.PatientMenuEditFieldLabel.FontSize=app.PatientMenuEditField.FontSize;
                
                app.EditPatientButton.Position(4)=app.PatientMenuPanel.Position(4)*(1/18);
                app.EditPatientButton.Position(3)=app.EditPatientButton.Position(4)*(10);
                app.EditPatientButton.Position(1)=app.PatientMenuPanel.Position(3)*(1/2)-app.EditPatientButton.Position(3)*(1/2);
                app.EditPatientButton.Position(2)=app.PatientMenuPanel.Position(4)*(6/9);
                app.EditPatientButton.FontSize=app.EditPatientButton.Position(4)-app.EditPatientButton.Position(4)*0.45;
                
                app.StartTrialButton.Position(4)=app.PatientMenuPanel.Position(4)*(1/18);
                app.StartTrialButton.Position(3)=app.EditPatientButton.Position(4)*(7);
                app.StartTrialButton.Position(1)=app.PatientMenuPanel.Position(3)*(1/2)-app.StartTrialButton.Position(3)*(1/2);
                app.StartTrialButton.Position(2)=app.PatientMenuPanel.Position(4)*(5/9);
                app.StartTrialButton.FontSize=app.StartTrialButton.Position(4)-app.StartTrialButton.Position(4)*0.45;
                
                app.ShowMedicalHistoryButton.Position(4)=app.PatientMenuPanel.Position(4)*(1/18);
                app.ShowMedicalHistoryButton.Position(3)=app.EditPatientButton.Position(4)*(10);
                app.ShowMedicalHistoryButton.Position(1)=app.PatientMenuPanel.Position(3)*(1/2)-app.ShowMedicalHistoryButton.Position(3)*(1/2);
                app.ShowMedicalHistoryButton.Position(2)=app.PatientMenuPanel.Position(4)*(4/9);
                app.ShowMedicalHistoryButton.FontSize=app.ShowMedicalHistoryButton.Position(4)-app.ShowMedicalHistoryButton.Position(4)*0.45;
                
                app.BackToStartPatientMenuButton.Position(4)=app.PatientMenuPanel.Position(4)*(1/18);
                app.BackToStartPatientMenuButton.Position(3)=app.EditPatientButton.Position(4)*(7);
                app.BackToStartPatientMenuButton.Position(1)=app.PatientMenuPanel.Position(3)*(1/2)-app.BackToStartPatientMenuButton.Position(3)*(1/2);
                app.BackToStartPatientMenuButton.Position(2)=app.PatientMenuPanel.Position(4)*(3/9);
                app.BackToStartPatientMenuButton.FontSize=app.BackToStartPatientMenuButton.Position(4)-app.BackToStartPatientMenuButton.Position(4)*0.45;
                
                %MedicalHistoryPanel
                
                app.MedicalHistoryPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.BackToMenuMedicalHistoryButton.Position(3)=app.MedicalHistoryPanel.Position(3)*(1/9);
                app.BackToMenuMedicalHistoryButton.Position(4)=app.BackToMenuMedicalHistoryButton.Position(3)*(0.2);
                app.BackToMenuMedicalHistoryButton.Position(1)=app.MedicalHistoryPanel.Position(3)*(1/5)-app.BackToMenuMedicalHistoryButton.Position(3)*(1/2);
                app.BackToMenuMedicalHistoryButton.Position(2)=app.MedicalHistoryPanel.Position(4)*(1/28);
                app.BackToMenuMedicalHistoryButton.FontSize=app.BackToMenuMedicalHistoryButton.Position(4)-app.BackToMenuMedicalHistoryButton.Position(4)*0.45;
                
                %MedicalHistoryTree
                    
                app.MedicalHistoryTree.Position(1)=app.MedicalHistoryPanel.Position(1);
                app.MedicalHistoryTree.Position(2)=app.MedicalHistoryPanel.Position(4)*(2/14);
                app.MedicalHistoryTree.Position(3)=app.MedicalHistoryPanel.Position(3)*(1/4);
                app.MedicalHistoryTree.Position(4)=app.MedicalHistoryPanel.Position(4)*(12/14);
               
                    %PersonalDataPanel
                    
                app.PersonalDataPanel.Position(1)=app.MedicalHistoryPanel.Position(3)*(1/4);
                app.PersonalDataPanel.Position(2)=app.MedicalHistoryPanel.Position(4)*(2/14);
                app.PersonalDataPanel.Position(3)=app.MedicalHistoryPanel.Position(3)*(3/4);
                app.PersonalDataPanel.Position(4)=app.MedicalHistoryPanel.Position(4)*(12/14);
                
                app.NameEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.NameEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.NameEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/5)-app.NameEditField2.Position(3)*(1/2);
                app.NameEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(11/14);
                app.NameEditField2.FontSize=app.NameEditField2.Position(4)-app.NameEditField2.Position(4)*0.45;
                app.NameEditFieldLabel2.Position(3)=app.NameEditField2.Position(3);
                app.NameEditFieldLabel2.Position(4)=app.NameEditField2.Position(4);
                app.NameEditFieldLabel2.Position(1)=app.NameEditField2.Position(1)-app.NameEditFieldLabel2.Position(3)-app.NameEditField2.Position(3)*(1/10);
                app.NameEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(11/14);
                app.NameEditFieldLabel2.FontSize=app.NameEditField2.FontSize;
                
                app.SurnamesEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.SurnamesEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.SurnamesEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/2)-app.SurnamesEditField2.Position(3)*(1/2);
                app.SurnamesEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(11/14);
                app.SurnamesEditField2.FontSize=app.SurnamesEditField2.Position(4)-app.SurnamesEditField2.Position(4)*0.45;
                app.SurnamesEditFieldLabel2.Position(3)=app.SurnamesEditField2.Position(3);
                app.SurnamesEditFieldLabel2.Position(4)=app.SurnamesEditField2.Position(4);
                app.SurnamesEditFieldLabel2.Position(1)=app.SurnamesEditField2.Position(1)-app.SurnamesEditFieldLabel2.Position(3)-app.SurnamesEditField2.Position(3)*(1/10);
                app.SurnamesEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(11/14);
                app.SurnamesEditFieldLabel2.FontSize=app.SurnamesEditField2.FontSize;
                
                app.IDEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.IDEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.IDEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/5)-app.IDEditField2.Position(3)*(1/2);
                app.IDEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.IDEditField2.FontSize=app.IDEditField2.Position(4)-app.IDEditField2.Position(4)*0.45;
                app.IDEditFieldLabel2.Position(3)=app.IDEditField2.Position(3);
                app.IDEditFieldLabel2.Position(4)=app.IDEditField2.Position(4);
                app.IDEditFieldLabel2.Position(1)=app.IDEditField2.Position(1)-app.IDEditFieldLabel2.Position(3)-app.IDEditField2.Position(3)*(1/10);
                app.IDEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.IDEditFieldLabel2.FontSize=app.IDEditField2.FontSize;
                
                app.SpasticityLevelEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.SpasticityLevelEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.SpasticityLevelEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(2/4)-app.SpasticityLevelEditField2.Position(3)*(1/2);
                app.SpasticityLevelEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.SpasticityLevelEditField2.FontSize=app.SpasticityLevelEditField2.Position(4)-app.SpasticityLevelEditField2.Position(4)*0.45;
                app.SpasticityLevelEditFieldLabel2.Position(3)=app.SpasticityLevelEditField2.Position(3)*2;
                app.SpasticityLevelEditFieldLabel2.Position(4)=app.SpasticityLevelEditField2.Position(4);
                app.SpasticityLevelEditFieldLabel2.Position(1)=app.SpasticityLevelEditField2.Position(1)-app.SpasticityLevelEditFieldLabel2.Position(3)-app.SpasticityLevelEditField2.Position(3)*(1/10);
                app.SpasticityLevelEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.SpasticityLevelEditFieldLabel2.FontSize=app.SpasticityLevelEditField2.FontSize;
                
                app.AgeEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.AgeEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.AgeEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(3/4)-app.AgeEditField2.Position(3)*(1/2);
                app.AgeEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.AgeEditField2.FontSize=app.AgeEditField2.Position(4)-app.AgeEditField2.Position(4)*0.45;
                app.AgeEditFieldLabel2.Position(3)=app.AgeEditField2.Position(3);
                app.AgeEditFieldLabel2.Position(4)=app.AgeEditField2.Position(4);
                app.AgeEditFieldLabel2.Position(1)=app.AgeEditField2.Position(1)-app.AgeEditFieldLabel2.Position(3)-app.AgeEditField2.Position(3)*(1/10);
                app.AgeEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.AgeEditFieldLabel2.FontSize=app.AgeEditField2.FontSize;
                
                app.HeightEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.HeightEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.HeightEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/5)-app.HeightEditField2.Position(3)*(1/2);
                app.HeightEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.HeightEditField2.FontSize=app.HeightEditField2.Position(4)-app.HeightEditField2.Position(4)*0.45;
                app.HeightEditFieldLabel2.Position(3)=app.HeightEditField2.Position(3);
                app.HeightEditFieldLabel2.Position(4)=app.HeightEditField2.Position(4);
                app.HeightEditFieldLabel2.Position(1)=app.HeightEditField2.Position(1)-app.HeightEditFieldLabel2.Position(3)-app.HeightEditField2.Position(3)*(1/10);
                app.HeightEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.HeightEditFieldLabel2.FontSize=app.HeightEditField2.FontSize;
                
                app.WeightEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.WeightEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.WeightEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(2/4)-app.WeightEditField2.Position(3)*(1/2);
                app.WeightEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.WeightEditField2.FontSize=app.WeightEditField2.Position(4)-app.WeightEditField2.Position(4)*0.45;
                app.WeightEditFieldLabel2.Position(3)=app.WeightEditField2.Position(3);
                app.WeightEditFieldLabel2.Position(4)=app.WeightEditField2.Position(4);
                app.WeightEditFieldLabel2.Position(1)=app.WeightEditField2.Position(1)-app.WeightEditFieldLabel2.Position(3)-app.WeightEditField2.Position(3)*(1/10);
                app.WeightEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.WeightEditFieldLabel2.FontSize=app.WeightEditField2.FontSize;
                
                app.GenderEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.GenderEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.GenderEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(3/4)-app.GenderEditField2.Position(3)*(1/2);
                app.GenderEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.GenderEditField2.FontSize=app.GenderEditField2.Position(4)-app.GenderEditField2.Position(4)*0.45;
                app.GenderEditFieldLabel2.Position(3)=app.GenderEditField2.Position(3);
                app.GenderEditFieldLabel2.Position(4)=app.GenderEditField2.Position(4);
                app.GenderEditFieldLabel2.Position(1)=app.GenderEditField2.Position(1)-app.GenderEditFieldLabel2.Position(3)-app.GenderEditField2.Position(3)*(1/10);
                app.GenderEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.GenderEditFieldLabel2.FontSize=app.GenderEditField2.FontSize;
                
                app.LeftArmLabel2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftArmLabel2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftArmLabel2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftArmLabel2.Position(3)*(1/2);
                app.LeftArmLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(7/14);
                app.LeftArmLabel2.FontSize=app.LeftArmLabel2.Position(4)-app.LeftArmLabel2.Position(4)*0.45;
                
                app.RightArmLabel2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightArmLabel2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightArmLabel2.Position(1)=app.PersonalDataPanel.Position(3)*(2/3)-app.RightArmLabel2.Position(3)*(1/2);
                app.RightArmLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(7/14);
                app.RightArmLabel2.FontSize=app.RightArmLabel2.Position(4)-app.RightArmLabel2.Position(4)*0.45;
                
                app.LeftWristElbowEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftWristElbowEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftWristElbowEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftWristElbowEditField2.Position(3)*(1/2);
                app.LeftWristElbowEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(6/14);
                app.LeftWristElbowEditField2.FontSize=app.LeftWristElbowEditField2.Position(4)-app.LeftWristElbowEditField2.Position(4)*0.45;
                app.LeftWristElbowEditFieldLabel2.Position(3)=app.LeftWristElbowEditField2.Position(3);
                app.LeftWristElbowEditFieldLabel2.Position(4)=app.LeftWristElbowEditField2.Position(4);
                app.LeftWristElbowEditFieldLabel2.Position(1)=app.LeftWristElbowEditField2.Position(1)-app.LeftWristElbowEditFieldLabel2.Position(3)-app.LeftWristElbowEditField2.Position(3)*(1/10);
                app.LeftWristElbowEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(6/14);
                app.LeftWristElbowEditFieldLabel2.FontSize=app.LeftWristElbowEditField2.FontSize;
                
                app.RightWristElbowEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightWristElbowEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightWristElbowEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(2/3)-app.RightWristElbowEditField2.Position(3)*(1/2);
                app.RightWristElbowEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(6/14);
                app.RightWristElbowEditField2.FontSize=app.RightWristElbowEditField2.Position(4)-app.RightWristElbowEditField2.Position(4)*0.45;
                app.RightWristElbowEditFieldLabel2.Position(3)=app.RightWristElbowEditField2.Position(3);
                app.RightWristElbowEditFieldLabel2.Position(4)=app.RightWristElbowEditField2.Position(4);
                app.RightWristElbowEditFieldLabel2.Position(1)=app.RightWristElbowEditField2.Position(1)-app.RightWristElbowEditFieldLabel2.Position(3)-app.RightWristElbowEditField2.Position(3)*(1/10);
                app.RightWristElbowEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(6/14);
                app.RightWristElbowEditFieldLabel2.FontSize=app.RightWristElbowEditField2.FontSize;
                
                app.LeftElbowShoulderEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftElbowShoulderEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftElbowShoulderEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftElbowShoulderEditField2.Position(3)*(1/2);
                app.LeftElbowShoulderEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(5/14);
                app.LeftElbowShoulderEditField2.FontSize=app.LeftElbowShoulderEditField2.Position(4)-app.LeftElbowShoulderEditField2.Position(4)*0.45;
                app.LeftElbowShoulderEditFieldLabel2.Position(3)=app.LeftElbowShoulderEditField2.Position(3);
                app.LeftElbowShoulderEditFieldLabel2.Position(4)=app.LeftElbowShoulderEditField2.Position(4);
                app.LeftElbowShoulderEditFieldLabel2.Position(1)=app.LeftElbowShoulderEditField2.Position(1)-app.LeftElbowShoulderEditFieldLabel2.Position(3)-app.LeftElbowShoulderEditField2.Position(3)*(1/10);
                app.LeftElbowShoulderEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(5/14);
                app.LeftElbowShoulderEditFieldLabel2.FontSize=app.LeftElbowShoulderEditField2.FontSize;
                
                app.RightElbowShoulderEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightElbowShoulderEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightElbowShoulderEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(2/3)-app.RightElbowShoulderEditField2.Position(3)*(1/2);
                app.RightElbowShoulderEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(5/14);
                app.RightElbowShoulderEditField2.FontSize=app.RightElbowShoulderEditField2.Position(4)-app.RightElbowShoulderEditField2.Position(4)*0.45;
                app.RightElbowShoulderEditFieldLabel2.Position(3)=app.RightElbowShoulderEditField2.Position(3);
                app.RightElbowShoulderEditFieldLabel2.Position(4)=app.RightElbowShoulderEditField2.Position(4);
                app.RightElbowShoulderEditFieldLabel2.Position(1)=app.RightElbowShoulderEditField2.Position(1)-app.RightElbowShoulderEditFieldLabel2.Position(3)-app.RightElbowShoulderEditField2.Position(3)*(1/10);
                app.RightElbowShoulderEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(5/14);
                app.RightElbowShoulderEditFieldLabel2.FontSize=app.RightElbowShoulderEditField2.FontSize;
                
                app.LeftShoulderBreastboneEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftShoulderBreastboneEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftShoulderBreastboneEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftShoulderBreastboneEditField2.Position(3)*(1/2);
                app.LeftShoulderBreastboneEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(4/14);
                app.LeftShoulderBreastboneEditField2.FontSize=app.LeftShoulderBreastboneEditField2.Position(4)-app.LeftShoulderBreastboneEditField2.Position(4)*0.45;
                app.LeftShoulderBreastboneEditFieldLabel2.Position(3)=app.LeftShoulderBreastboneEditField2.Position(3);
                app.LeftShoulderBreastboneEditFieldLabel2.Position(4)=app.LeftShoulderBreastboneEditField2.Position(4);
                app.LeftShoulderBreastboneEditFieldLabel2.Position(1)=app.LeftShoulderBreastboneEditField2.Position(1)-app.LeftShoulderBreastboneEditFieldLabel2.Position(3)-app.LeftShoulderBreastboneEditField2.Position(3)*(1/10);
                app.LeftShoulderBreastboneEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(4/14);
                app.LeftShoulderBreastboneEditFieldLabel2.FontSize=app.LeftShoulderBreastboneEditField2.FontSize;
                
                app.RightShoulderBreastboneEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightShoulderBreastboneEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightShoulderBreastboneEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(2/3)-app.RightShoulderBreastboneEditField2.Position(3)*(1/2);
                app.RightShoulderBreastboneEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(4/14);
                app.RightShoulderBreastboneEditField2.FontSize=app.RightShoulderBreastboneEditField2.Position(4)-app.RightShoulderBreastboneEditField2.Position(4)*0.45;
                app.RightShoulderBreastboneEditFieldLabel2.Position(3)=app.RightShoulderBreastboneEditField2.Position(3);
                app.RightShoulderBreastboneEditFieldLabel2.Position(4)=app.RightShoulderBreastboneEditField2.Position(4);
                app.RightShoulderBreastboneEditFieldLabel2.Position(1)=app.RightShoulderBreastboneEditField2.Position(1)-app.RightShoulderBreastboneEditFieldLabel2.Position(3)-app.RightShoulderBreastboneEditField2.Position(3)*(1/10);
                app.RightShoulderBreastboneEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(4/14);
                app.RightShoulderBreastboneEditFieldLabel2.FontSize=app.RightShoulderBreastboneEditField2.FontSize;
                
                app.LeftWristHandleEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftWristHandleEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftWristHandleEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftWristHandleEditField2.Position(3)*(1/2);
                app.LeftWristHandleEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(3/14);
                app.LeftWristHandleEditField2.FontSize=app.LeftWristHandleEditField2.Position(4)-app.LeftWristHandleEditField2.Position(4)*0.45;
                app.LeftWristHandleEditFieldLabel2.Position(3)=app.LeftWristHandleEditField2.Position(3);
                app.LeftWristHandleEditFieldLabel2.Position(4)=app.LeftWristHandleEditField2.Position(4);
                app.LeftWristHandleEditFieldLabel2.Position(1)=app.LeftWristHandleEditField2.Position(1)-app.LeftWristHandleEditFieldLabel2.Position(3)-app.LeftWristHandleEditField2.Position(3)*(1/10);
                app.LeftWristHandleEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(3/14);
                app.LeftWristHandleEditFieldLabel2.FontSize=app.LeftWristHandleEditField2.FontSize;
                
                app.RightWristHandleEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightWristHandleEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightWristHandleEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(2/3)-app.RightWristHandleEditField2.Position(3)*(1/2);
                app.RightWristHandleEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(3/14);
                app.RightWristHandleEditField2.FontSize=app.RightWristHandleEditField2.Position(4)-app.RightWristHandleEditField2.Position(4)*0.45;
                app.RightWristHandleEditFieldLabel2.Position(3)=app.RightWristHandleEditField2.Position(3);
                app.RightWristHandleEditFieldLabel2.Position(4)=app.RightWristHandleEditField2.Position(4);
                app.RightWristHandleEditFieldLabel2.Position(1)=app.RightWristHandleEditField2.Position(1)-app.RightWristHandleEditFieldLabel2.Position(3)-app.RightWristHandleEditField2.Position(3)*(1/10);
                app.RightWristHandleEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(3/14);
                app.RightWristHandleEditFieldLabel2.FontSize=app.RightWristHandleEditField2.FontSize;
                
                    %ResultsButtonsPanel
                    
                app.ResultsButtonsPanel.Position(1)=app.MedicalHistoryPanel.Position(3)*(1/4);
                app.ResultsButtonsPanel.Position(2)=app.MedicalHistoryPanel.Position(4)*(2/14);
                app.ResultsButtonsPanel.Position(3)=app.MedicalHistoryPanel.Position(3)*(3/8);
                app.ResultsButtonsPanel.Position(4)=app.MedicalHistoryPanel.Position(4)*(12/14);
                
                app.TrajectoryLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.TrajectoryLabel.Position(3)*(1/2);
                app.TrajectoryLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(15/16);
                
                app.JointLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.JointLabel.Position(3)*(1/2);
                app.JointLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(14/16);
                
                app.qButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.qButton.Position(3)*(1/2);
                app.qButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(13/16);
                
                app.dqButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(2/4)-app.dqButton.Position(3)*(1/2);
                app.dqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(13/16);
                
                app.ddqButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(3/4)-app.ddqButton.Position(3)*(1/2);
                app.ddqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(13/16);
                
                app.JointPrecisionLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.JointPrecisionLabel.Position(3)*(1/2);
                app.JointPrecisionLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(12/16);
                
                app.EpqButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(2/4)-app.EpqButton.Position(3)*(1/2);
                app.EpqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(12/16);
                
                app.EpdqButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(3/4)-app.EpdqButton.Position(3)*(1/2);
                app.EpdqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(12/16);
                
                app.EpddqButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(4/4)-app.EpddqButton.Position(3)*(1/2);
                app.EpddqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(12/16);
                
                app.JointAccuracyLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.JointAccuracyLabel.Position(3)*(1/2);
                app.JointAccuracyLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(11/16);
                
                app.EqButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(2/4)-app.EqButton.Position(3)*(1/2);
                app.EqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(11/16);
                
                app.EdqButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(3/4)-app.EdqButton.Position(3)*(1/2);
                app.EdqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(11/16);
                
                app.EddqButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(4/4)-app.EddqButton.Position(3)*(1/2);
                app.EddqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(11/16);
                
                app.CartesianLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.CartesianLabel.Position(3)*(1/2);
                app.CartesianLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(10/16);
                
                app.xButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.xButton.Position(3)*(1/2);
                app.xButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(9/16);
                
                app.dxButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(2/4)-app.dxButton.Position(3)*(1/2);
                app.dxButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(9/16);
                
                app.ThreeDButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(3/4)-app.ThreeDButton.Position(3)*(1/2);
                app.ThreeDButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(9/16);
                
                app.CartesianPrecisionLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(2/4)-app.CartesianPrecisionLabel.Position(3)*(1/2);
                app.CartesianPrecisionLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(8/16);
                
                app.EpxButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(3/4)-app.EpxButton.Position(3)*(1/2);
                app.EpxButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(8/16);
                
                app.EpdxButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(4/4)-app.EpdxButton.Position(3)*(1/2);
                app.EpdxButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(8/16);
                
                app.CartesianAccuracyLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(2/4)-app.CartesianAccuracyLabel.Position(3)*(1/2);
                app.CartesianAccuracyLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(7/16);
                
                app.ExButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(3/4)-app.ExButton.Position(3)*(1/2);
                app.ExButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(7/16);
                
                app.EdxButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(4/4)-app.EdxButton.Position(3)*(1/2);
                app.EdxButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(7/16);
                
                app.ForceSensorLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.ForceSensorLabel.Position(3)*(1/2);
                app.ForceSensorLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(6/16);
                
                app.FTButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.FTButton.Position(3)*(1/2);
                app.FTButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(5/16);
                
                app.DelsysSensorsLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.DelsysSensorsLabel.Position(3)*(1/2);
                app.DelsysSensorsLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(4/16);
                
                app.EMGButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.EMGButton.Position(3)*(1/2);
                app.EMGButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(3/16);
                
                app.AccelerationsButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.AccelerationsButton.Position(3)*(1/2);
                app.AccelerationsButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(2/16);
                
                app.AngularVelocityButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/4)-app.AngularVelocityButton.Position(3)*(1/2);
                app.AngularVelocityButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(1/16);
                
                    %TrialDataPanel
                    
                app.TrialDataPanel.Position(1)=app.MedicalHistoryPanel.Position(3)*(5/8);
                app.TrialDataPanel.Position(2)=app.MedicalHistoryPanel.Position(4)*(2/14);
                app.TrialDataPanel.Position(3)=app.MedicalHistoryPanel.Position(3)*(3/8);
                app.TrialDataPanel.Position(4)=app.MedicalHistoryPanel.Position(4)*(12/14);
                
                app.TrialDataPatientEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataPatientEditField.Position(3)*(1/2);
                app.TrialDataPatientEditField.Position(2)=app.TrialDataPanel.Position(4)*(6/7);
                
                app.TrialDataChoosedArmEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataChoosedArmEditField.Position(3)*(1/2);
                app.TrialDataChoosedArmEditField.Position(2)=app.TrialDataPanel.Position(4)*(5/7);
                
                app.TrialDataTrajectoryDateCapturedEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataTrajectoryDateCapturedEditField.Position(3)*(1/2);
                app.TrialDataTrajectoryDateCapturedEditField.Position(2)=app.TrialDataPanel.Position(4)*(4/7);
                
                app.TrialDataTrajectoryDateExecutedEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataTrajectoryDateExecutedEditField.Position(3)*(1/2);
                app.TrialDataTrajectoryDateExecutedEditField.Position(2)=app.TrialDataPanel.Position(4)*(3/7);
                
                %PlotsPanel
                
                app.PlotsPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.CloseImage.Position(1)=app.PlotsPanel.Position(3)-app.CloseImage.Position(3)-10;
                app.CloseImage.Position(2)=app.PlotsPanel.Position(4)-app.CloseImage.Position(4)-10;
                
                drawnow;
                
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');
            end
            
        end
        
        function getMonth(app,n)
            try
                switch (n)
                    case '00'
                        app.Month='Prueba';
                    case '01'
                        app.Month='Enero';
                    case '02'
                        app.Month='Febrero';
                    case '03'
                        app.Month='Marzo';
                    case '04'
                        app.Month='Abril';
                    case '05'
                        app.Month='Mayo';
                    case '06'
                        app.Month='Junio';
                    case '07'
                        app.Month='Julio';
                    case '08'
                        app.Month='Agosto';
                    case '09'
                        app.Month='Septiembre';
                    case '10'
                        app.Month='Octubre';
                    case '11'
                        app.Month='Noviembre';
                    case '12'
                        app.Month='Diciembre';
                    otherwise
                        app.Month='Error';
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');
            end
            
        end
        
        function resetNewPatient(app)
            try
                app.SurnamesEditField.Value='';
                app.NameEditField.Value='';
                app.SpasticityLevelEditField.Value=0;
                app.AgeEditField.Value=0;
                app.HeightEditField.Value=0;
                app.WeightEditField.Value=0;
                app.LeftWristElbowEditField.Value=0;
                app.LeftElbowShoulderEditField.Value=0;
                app.LeftShoulderBreastboneEditField.Value=0;
                app.LeftWristHandleEditField.Value=0;
                app.RightWristElbowEditField.Value=0;
                app.RightElbowShoulderEditField.Value=0;
                app.RightShoulderBreastboneEditField.Value=0;
                app.RightWristHandleEditField.Value=0;
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');
            end
        end
        
        function EnableResultsButtons(app)
            try
                Trials=app.TrialsLoad;
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                app.DisableResultsButtons();
                Arm=Trials(selectedNodes(1).NodeData).Conditions.Arm;
                ArmError=0;
                TrajectoryError=0;
                FTError=0;
                DelsysError=0;
                for j=1:size(selectedNodes,1)
                    if (Trials(selectedNodes(j).NodeData).Conditions.Arm~=Arm)
                        ArmError=1;
                    end
                    if (isempty(Trials(selectedNodes(j).NodeData).Trajectory))
                        TrajectoryError=1;
                    end
                    if (isempty(Trials(selectedNodes(j).NodeData).FTSensor))
                        FTError=1;
                    end
                    if (isempty(Trials(selectedNodes(j).NodeData).DelsysSensors))
                        DelsysError=1;
                    end
                end
                if ArmError==1
                    uialert(app.UIFigure,'Todas las pruebas seleccionadas deben haber sido realizadas con el mismo brazo','Error cargando los resultados');
                else
                    if (TrajectoryError==0)
                        app.qButton.Enable='on';
                        app.dqButton.Enable='on';
                        app.ddqButton.Enable='on';
                        app.EpqButton.Enable='on';
                        app.EpdqButton.Enable='on';
                        app.EpddqButton.Enable='on';
                        app.xButton.Enable='on';
                        app.dxButton.Enable='on';
                        app.ThreeDButton.Enable='on';
                        app.EpxButton.Enable='on';
                        app.EpdxButton.Enable='on';
                    end
                    if (FTError==0)
                        app.FTButton.Enable='on';
                    end
                    if (DelsysError==0)
                        app.EMGButton.Enable='on';
                        app.AngularVelocityButton.Enable='on';
                        app.AccelerationsButton.Enable='on';
                    end
                    if (TrajectoryError==0)&&(size(selectedNodes,1)>=2)
                        app.EqButton.Enable='on';
                        app.EdqButton.Enable='on';
                        app.EddqButton.Enable='on';
                        app.ExButton.Enable='on';
                        app.EdxButton.Enable='on';
                    end
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end
        
        function DisableResultsButtons(app)
            app.qButton.Enable='off';
            app.dqButton.Enable='off';
            app.ddqButton.Enable='off';
            app.EqButton.Enable='off';
            app.EdqButton.Enable='off';
            app.EddqButton.Enable='off';
            app.EpqButton.Enable='off';
            app.EpdqButton.Enable='off';
            app.EpddqButton.Enable='off';
            app.xButton.Enable='off';
            app.dxButton.Enable='off';
            app.ThreeDButton.Enable='off';
            app.ExButton.Enable='off';
            app.EdxButton.Enable='off';
            app.EpxButton.Enable='off';
            app.EpdxButton.Enable='off';
            app.FTButton.Enable='off';
            app.EMGButton.Enable='off';
            app.AngularVelocityButton.Enable='off';
            app.AccelerationsButton.Enable='off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState='Maximized';
            
            app.ResizeApp();
            
            %Initialize IIWAControlObject
            app.IIWAControlObject = IIWAControl(app.ROSConnected);
            
            %Initialize FTSensorObject
            app.FTSensorObject = FTSensor(app.FTSensorConnected);
            
            %Initialize DelsysSensors
            app.DelsysSensorsObject = DelsysSensors(app.DelsysSensorsConnected);
            
            %Components
            app.CloseImage.Tag='CloseImage';
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            app.ResizeApp();
            
        end

        % Button pushed function: SavePatientButton
        function SavePatientButtonPushed(app, event)
            try
                namesidFile = matfile(app.NamesIDDatabasesFilePath, 'Writable',true);
                Person.Apellidos=app.SurnamesEditField.Value;
                Person.Nombre=app.NameEditField.Value;
                Person.ID=app.IDEditField.Value;
                app.IDPatient=app.IDEditField.Value;
                namesidFile.Person(app.IDEditField.Value,1) = Person; 
                patientsFile = matfile(app.PatientsDatabasesFilePath, 'Writable', true);
                Patient.ID=app.IDEditField.Value;
                Patient.Espasticidad=app.SpasticityLevelEditField.Value;
                Patient.Edad=app.AgeEditField.Value;
                Patient.Altura=app.HeightEditField.Value;
                Patient.Peso=app.WeightEditField.Value;
                Patient.Genero=app.GenderDropDown.Value;
                Patient.MedidasBrazoIzq.MunecaCodoIzq=app.LeftWristElbowEditField.Value;
                Patient.MedidasBrazoIzq.CodoHombroIzq=app.LeftElbowShoulderEditField.Value;
                Patient.MedidasBrazoIzq.HombroEsternonIzq=app.LeftShoulderBreastboneEditField.Value;
                Patient.MedidasBrazoIzq.MunecaAgarradorIzq=app.LeftWristHandleEditField.Value;
                Patient.MedidasBrazoDcha.MunecaCodoDcha=app.RightWristElbowEditField.Value;
                Patient.MedidasBrazoDcha.CodoHombroDcha=app.RightElbowShoulderEditField.Value;
                Patient.MedidasBrazoDcha.HombroEsternonDcha=app.RightShoulderBreastboneEditField.Value;
                Patient.MedidasBrazoDcha.MunecaAgarradorDcha=app.RightWristHandleEditField.Value;
                patientsFile.Patient(app.IDEditField.Value,1) = Patient;
                disp(['Salvado el paciente ', num2str(app.IDEditField.Value)]);
                uialert(app.UIFigure,'Paciente Guardado','','Icon','success');
                app.BackToStartPatientDataButton.Visible='on';
                app.BackToMenuPatientDataButton.Visible='off';
                app.PatientDataPanel.Visible='off';
                app.PatientMenuPanel.Visible='on';
                app.PatientMenuEditField.Value=app.IDPatient;
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: NewPatientButton
        function NewPatientButtonPushed(app, event)
            try
                app.resetNewPatient();
                patientsFile = matfile(app.PatientsDatabasesFilePath, 'Writable', true);
                if (~isempty(who(patientsFile)))
                    npatient=size(patientsFile.Patient,1);
                    app.IDEditField.Value=npatient+1;
                else
                    app.IDEditField.Value=1;
                end
                app.StartPanel.Visible='off';
                app.PatientDataPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: LoadPatientButton
        function LoadPatientButtonPushed(app, event)
            try
                namesidFile = matfile(app.NamesIDDatabasesFilePath, 'Writable', true);
                if (~isempty(who(namesidFile)))
                    load(app.NamesIDDatabasesFilePath)
                    if size(namesidFile.Person,1)==1
                        app.LoadPatientTable.Data = {Person(1).Apellidos,Person(1).Nombre,Person(1).ID}; %#ok<NODEF> 
    
                    else
                        Person=struct2table(Person); %#ok<NODEF> 
                        Person=sortrows(Person,1,'ascend');
                        app.LoadPatientTable.Data = Person;
                    end 
                else
                    uialert(app.UIFigure,'No hay pacientes guardados','Error cargando pacientes');
                end
                s = uistyle('BackgroundColor','white');
                addStyle(app.LoadPatientTable,s);
                app.StartPanel.Visible='off';
                app.LoadPatientPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Cell selection callback: LoadPatientTable
        function LoadPatientTableCellSelection(app, event)
           Indices=event.Indices;
           app.IDPatient=app.LoadPatientTable.Data{Indices(1),3};
           s = uistyle('BackgroundColor','white');
           addStyle(app.LoadPatientTable,s);
           s = uistyle('BackgroundColor',[0.6308 0.7991 0.9216]);
           addStyle(app.LoadPatientTable,s,'row',Indices(1));
            
        end

        % Button pushed function: EditPatientButton
        function EditPatientButtonPushed(app, event)
            try
                load(app.PatientsDatabasesFilePath)
                load(app.NamesIDDatabasesFilePath)
                app.NameEditField.Value=Person(app.IDPatient).Nombre;
                app.SurnamesEditField.Value=Person(app.IDPatient).Apellidos;
                app.IDEditField.Value=Patient(app.IDPatient).ID;
                app.SpasticityLevelEditField.Value=Patient(app.IDPatient).Espasticidad;
                app.AgeEditField.Value=Patient(app.IDPatient).Edad;
                app.HeightEditField.Value=Patient(app.IDPatient).Altura;
                app.WeightEditField.Value=Patient(app.IDPatient).Peso;
                app.GenderDropDown.Value=Patient(app.IDPatient).Genero;
                app.LeftWristElbowEditField.Value=Patient(app.IDPatient).MedidasBrazoIzq.MunecaCodoIzq;
                app.LeftElbowShoulderEditField.Value=Patient(app.IDPatient).MedidasBrazoIzq.CodoHombroIzq;
                app.LeftShoulderBreastboneEditField.Value=Patient(app.IDPatient).MedidasBrazoIzq.HombroEsternonIzq;
                app.LeftWristHandleEditField.Value=Patient(app.IDPatient).MedidasBrazoIzq.MunecaAgarradorIzq;
                app.RightWristElbowEditField.Value=Patient(app.IDPatient).MedidasBrazoDcha.MunecaCodoDcha;
                app.RightElbowShoulderEditField.Value=Patient(app.IDPatient).MedidasBrazoDcha.CodoHombroDcha;
                app.RightShoulderBreastboneEditField.Value=Patient(app.IDPatient).MedidasBrazoDcha.HombroEsternonDcha;
                app.RightWristHandleEditField.Value=Patient(app.IDPatient).MedidasBrazoDcha.MunecaAgarradorDcha;
                app.BackToStartPatientDataButton.Visible='off';
                app.BackToMenuPatientDataButton.Visible='on';
                app.PatientMenuPanel.Visible='off';
                app.PatientDataPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: BackToStartPatientDataButton
        function BackToStartPatientDataButtonPushed(app, event)
            app.PatientDataPanel.Visible='off';
            app.StartPanel.Visible='on';
        end

        % Button pushed function: ChoosePatientButton
        function ChoosePatientButtonPushed(app, event)
            app.PatientMenuEditField.Value=app.IDPatient;
            app.LoadPatientPanel.Visible='off';
            app.PatientMenuPanel.Visible='on';
        end

        % Button pushed function: BackToStartLoadPatientButton
        function BackToStartLoadPatientButtonPushed(app, event)
            app.LoadPatientPanel.Visible='off';
            app.StartPanel.Visible='on';
        end

        % Button pushed function: BackToStartPatientMenuButton
        function BackToStartPatientMenuButtonPushed(app, event)
            app.PatientMenuPanel.Visible='off';
            app.StartPanel.Visible='on';    
        end

        % Button pushed function: ShowMedicalHistoryButton
        function ShowMedicalHistoryButtonPushed(app, event)
            try    
                app.PersonalDataPanel.Visible='off';
                app.TrialDataPanel.Visible='off';
                app.ResultsButtonsPanel.Visible='off';
                delete(app.MedicalHistoryTree.Children)
                load(app.PatientsDatabasesFilePath) 
                load(app.NamesIDDatabasesFilePath)  
                trialsFile = matfile(app.TrialsDatabasesFilePath);
                app.TrialsLoad=trialsFile.Trials;
                NameNode = uitreenode(app.MedicalHistoryTree,'Text', [Person(app.IDPatient).Nombre ' ' Person(app.IDPatient).Apellidos],'NodeData',[]);
                uitreenode(NameNode,'Text','Datos Personales','NodeData',[]);
                TrialsNode = uitreenode(NameNode,'Text','Pruebas','NodeData',[]);
                n=[];
                for j=1:size(app.TrialsLoad,1) %trialsFile.Trials,1)
                    if app.TrialsLoad(j).IDPatient==app.IDPatient
                        n(size(n,2)+1)=j; %#ok<AGROW> 
                    end
                end
                if size(n,2)~=0
                    dc=[app.TrialsLoad(n(1)).Conditions.DateCaptured];
                    for j=1:size(n,2)
                        updatedc=1;
                        for k=1:size(dc,1)
                            if (strcmp(app.TrialsLoad(n(j)).Conditions.DateCaptured,dc(k,:))==1)
                                updatedc=0;
                            end
                        end
                        if j~=1
                            dc(size(dc,1)+1,:)=app.TrialsLoad(n(j)).Conditions.DateCaptured;
                        end
                        if (j==1)||(updatedc==1)
                            app.getMonth(app.TrialsLoad(n(j)).Conditions.DateCaptured(3:4))
                            DateCaptured=['Trayectoria capturada el Día ' app.TrialsLoad(n(j)).Conditions.DateCaptured(5:6) ' de ' app.Month ' de 20' app.TrialsLoad(n(j)).Conditions.DateCaptured(1:2)];
                            CapturedTrayectoryNode=uitreenode(TrialsNode,'Text',DateCaptured,'NodeData',[]);
                            RightArmNode = uitreenode(CapturedTrayectoryNode,'Text','Brazo Derecho','NodeData',[]);
                            LeftArmNode = uitreenode(CapturedTrayectoryNode,'Text','Brazo Izquierdo','NodeData',[]);
                            del=[0]; %#ok<NBRAK> 
                            der=[0]; %#ok<NBRAK> 
                            for l=1:size(n,2)
                                if (strcmp(dc(size(dc,1),:),app.TrialsLoad(n(l)).Conditions.DateCaptured)==1)
                                    if (app.TrialsLoad(n(l)).Conditions.Arm=="L")
                                        if del==0
                                            del=[app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6)]; %#ok<NBRAK> 
                                            first=1;
                                        end
                                        updatedel=1;
                                        for m=1:size(del,1)
                                            if (strcmp(app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6),del(m,:))==1)
                                                updatedel=0;
                                            end
                                        end
                                        if first==0
                                            del(size(del,1)+1,:)=app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6);
                                        end
                                        if (first==1)||(updatedel==1)
                                            first=0;
                                            app.getMonth(app.TrialsLoad(n(l)).Conditions.DateExecuted(3:4))
                                            DateExecuted=['Trayectoria repetida el Día ' app.TrialsLoad(n(l)).Conditions.DateExecuted(5:6) ' de ' app.Month ' de 20' app.TrialsLoad(n(l)).Conditions.DateExecuted(1:2)];
                                            ExecutedTrayectoryNode = uitreenode(LeftArmNode,'Text',DateExecuted,'NodeData',[]);
                                            nTraj=1;
                                            for o=1:size(n,2)
                                                if (strcmp(app.TrialsLoad(n(o)).Conditions.DateExecuted(1:6),del(size(del,1),:))==1)&&(strcmp(app.TrialsLoad(n(o)).Conditions.DateCaptured,dc(size(dc,1),:))==1)&&(app.TrialsLoad(n(o)).Conditions.Arm=="L")
                                                    EndNode = uitreenode(ExecutedTrayectoryNode,'Text',['Prueba ' num2str(nTraj)] ,'NodeData',n(o)); %#ok<NASGU> 
                                                    nTraj=nTraj+1;
                                                end
                                            end
                                        end
                                   end
                                   if (app.TrialsLoad(n(l)).Conditions.Arm=="R") 
                                        if der==0
                                            der=[app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6)]; %#ok<NBRAK> 
                                            first=1;
                                        end
                                        updateder=1;
                                        for m=1:size(der,1)
                                            if (strcmp(app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6),der(size(der,1),:))==1)
                                                updateder=0;
                                            end
                                        end
                                        if first==0
                                            der(size(der,1)+1,:)=app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6);
                                        end
                                        if (first==1)||(updateder==1)
                                            first=0;
                                            app.getMonth(app.TrialsLoad(n(l)).Conditions.DateExecuted(3:4))
                                            DateExecuted=['Trayectoria repetida el Día ' app.TrialsLoad(n(l)).Conditions.DateExecuted(5:6) ' de ' app.Month ' de 20' app.TrialsLoad(n(l)).Conditions.DateExecuted(1:2)];
                                            ExecutedTrayectoryNode = uitreenode(RightArmNode,'Text',DateExecuted,'NodeData',[]);
                                            nTraj=1;
                                            for o=1:size(n,2)
                                                if (strcmp(app.TrialsLoad(n(o)).Conditions.DateExecuted(1:6),der(size(der,1),:))==1)&&(strcmp(app.TrialsLoad(n(o)).Conditions.DateCaptured,dc(size(dc,1),:))==1)&&(app.TrialsLoad(n(o)).Conditions.Arm=="R")
                                                    EndNode = uitreenode(ExecutedTrayectoryNode,'Text',['Prueba ' num2str(nTraj)] ,'NodeData',n(o)); %#ok<NASGU> 
                                                    nTraj=nTraj+1;
                                                end
                                            end
                                        end  
                                    end
                                end
                            end
                        end    
                    end
                end
                app.PatientMenuPanel.Visible='off';
                app.MedicalHistoryPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: BackToMenuMedicalHistoryButton
        function BackToMenuMedicalHistoryButtonPushed(app, event)
            app.MedicalHistoryPanel.Visible='off';
            app.PatientMenuPanel.Visible='on';
        end

        % Button pushed function: qButton
        function qButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointPositionTrajectories('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: dqButton
        function dqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointVelocityTrajectories('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EqButton
        function EqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.Trials.Load(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointPositionRepeatibilityError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EdqButton
        function EdqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianPositionTrajectories('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: ExButton
        function ExButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianPositionRepeatibilityError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EdxButton
        function EdxButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianVelocityRepeatibilityError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: ThreeDButton
        function ThreeDButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.Plot3DCartesianPositionTrajectories('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: dxButton
        function dxButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianVelocityTrajectories('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: FTButton
        function FTButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).FTSensor.ForceTorqueTrial;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).FTSensor.ForceTorqueTrial; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.DelsysSensorsObject.PlotGYRO('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EMGButton
        function EMGButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).DelsysSensors.Trial;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).DelsysSensors.Trial; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.DelsysSensorsObject.PlotEMG('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: AccelerationsButton
        function AccelerationsButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).DelsysSensors.Trial;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).DelsysSensors.Trial; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.DelsysSensorsObject.PlotACC('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: AngularVelocityButton
        function AngularVelocityButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).DelsysSensors.Trial;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).DelsysSensors.Trial; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.DelsysSensorsObject.PlotGYRO('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: ddqButton
        function ddqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointAccelerationTrajectories('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EddqButton
        function EddqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end 
        end

        % Button pushed function: EpqButton
        function EpqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointPositionError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EpdqButton
        function EpdqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointVelocityError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EpddqButton
        function EpddqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointAccelerationError('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EpxButton
        function EpxButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianPositionError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EpdxButton
        function EpdxButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianVelocityError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Image clicked function: CloseImage
        function CloseImageClicked(app, event)
            try    
                j = 1;
                for i = 1:max(size(app.PlotsPanel.Children))
                    if(~strcmp(app.PlotsPanel.Children(j).Tag, 'CloseImage'))
                        delete(app.PlotsPanel.Children(j));
                    else
                        j = j+1;
                    end
                end
                app.PlotsPanel.Visible='off';
                app.MedicalHistoryPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Selection changed function: MedicalHistoryTree
        function MedicalHistoryTreeSelectionChanged(app, event)
            selectedNodes = app.MedicalHistoryTree.SelectedNodes;
            if size(selectedNodes,1)==1
                if selectedNodes.Text=="Datos Personales"
                    app.PersonalDataPanel.Visible='off';
                    app.ResultsButtonsPanel.Visible='off';
                    app.IDEditField2.Value=app.IDPatient;
                    load(app.PatientsDatabasesFilePath)
                    load(app.NamesIDDatabasesFilePath)
                    app.NameEditField2.Value=Person(app.IDPatient).Nombre;
                    app.SurnamesEditField2.Value=Person(app.IDPatient).Apellidos;
                    app.IDEditField2.Value=Patient(app.IDPatient).ID;
                    app.SpasticityLevelEditField2.Value=Patient(app.IDPatient).Espasticidad;
                    app.AgeEditField2.Value=Patient(app.IDPatient).Edad;
                    app.HeightEditField2.Value=Patient(app.IDPatient).Altura;
                    app.WeightEditField2.Value=Patient(app.IDPatient).Peso;
                    app.GenderEditField2.Value=Patient(app.IDPatient).Genero;
                    app.LeftWristElbowEditField2.Value=Patient(app.IDPatient).MedidasBrazoIzq.MunecaCodoIzq;
                    app.LeftElbowShoulderEditField2.Value=Patient(app.IDPatient).MedidasBrazoIzq.CodoHombroIzq;
                    app.LeftShoulderBreastboneEditField2.Value=Patient(app.IDPatient).MedidasBrazoIzq.HombroEsternonIzq;
                    app.LeftWristHandleEditField2.Value=Patient(app.IDPatient).MedidasBrazoIzq.MunecaAgarradorIzq;
                    app.RightWristElbowEditField2.Value=Patient(app.IDPatient).MedidasBrazoDcha.MunecaCodoDcha;
                    app.RightElbowShoulderEditField2.Value=Patient(app.IDPatient).MedidasBrazoDcha.CodoHombroDcha;
                    app.RightShoulderBreastboneEditField2.Value=Patient(app.IDPatient).MedidasBrazoDcha.HombroEsternonDcha;
                    app.RightWristHandleEditField2.Value=Patient(app.IDPatient).MedidasBrazoDcha.MunecaAgarradorDcha;
                    app.PersonalDataPanel.Visible='on';
                elseif (selectedNodes.Text(1:6)=="Prueba")&&(selectedNodes.Text(7)~="s")
                    app.PersonalDataPanel.Visible='off';
                    load(app.NamesIDDatabasesFilePath)
                    load(app.TrialsDatabasesFilePath)
                    app.TrialDataPatientEditField.Value=['Paciente: ' Person(Trials(selectedNodes.NodeData).IDPatient).Nombre ' ' Person(Trials(selectedNodes.NodeData).IDPatient).Apellidos];
                    if Trials(selectedNodes.NodeData).Conditions.Arm=='L'
                        app.TrialDataChoosedArmEditField.Value='Brazo Utilizado: Brazo Izquierdo';    
                    else
                        app.TrialDataChoosedArmEditField.Value='Brazo Utilizado: Brazo Derecho';
                    end
                    app.getMonth(Trials(selectedNodes.NodeData).Conditions.DateExecuted(3:4))
                    DateExecuted=['Trayectoria repetida el Día ' Trials(selectedNodes.NodeData).Conditions.DateExecuted(5:6) ' de ' app.Month ' de 20' Trials(selectedNodes.NodeData).Conditions.DateExecuted(1:2) ' a las ' Trials(selectedNodes.NodeData).Conditions.DateExecuted(8:9) ':' Trials(selectedNodes.NodeData).Conditions.DateExecuted(10:11) ':' Trials(selectedNodes.NodeData).Conditions.DateExecuted(12:13) ''];
                    app.TrialDataTrajectoryDateExecutedEditField.Value=DateExecuted;
                    app.getMonth(Trials(selectedNodes.NodeData).Conditions.DateCaptured(3:4))
                    DateCaptured=['Trayectoria capturada el Día ' Trials(selectedNodes.NodeData).Conditions.DateCaptured(5:6) ' de ' app.Month ' de 20' Trials(selectedNodes.NodeData).Conditions.DateCaptured(1:2)];
                    app.TrialDataTrajectoryDateCapturedEditField.Value=DateCaptured;
                    app.EnableResultsButtons();
                    app.TrialDataPanel.Visible='on';
                    app.ResultsButtonsPanel.Visible='on';
                end
            else
                app.EnableResultsButtons();
                app.ResultsButtonsPanel.Visible='on';    
            end
            
        end

        % Button pushed function: BackToMenuPatientDataButton
        function BackToMenuPatientDataButtonPushed(app, event)
            app.PatientDataPanel.Visible='off';
            app.PatientMenuPanel.Visible='on';
            app.BackToStartPatientDataButton.Visible='on';
            app.BackToMenuPatientDataButton.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.StartPanel = uipanel(app.UIFigure);
            app.NewPatientButton = uibutton(app.StartPanel, 'push');
            app.NewPatientButton.ButtonPushedFcn = createCallbackFcn(app, @NewPatientButtonPushed, true);
            app.NewPatientButton.Text = 'NUEVO PACIENTE';
            app.LoadPatientButton = uibutton(app.StartPanel, 'push');
            app.LoadPatientButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPatientButtonPushed, true);
            app.LoadPatientButton.Text = 'CARGAR PACIENTE';
            app.PatientDataPanel = uipanel(app.UIFigure);
            app.PatientDataPanel.Visible = 'off';
            app.PatientDataLabel = uilabel(app.PatientDataPanel);
            app.PatientDataLabel.Text = 'Introducir Datos del Paciente';
            app.LeftArmLabel = uilabel(app.PatientDataPanel);
            app.LeftArmLabel.Text = 'Medidas Brazo Izquierdo';
            app.RightArmLabel = uilabel(app.PatientDataPanel);
            app.RightArmLabel.Text = 'Medidas Brazo Derecho';
            app.SavePatientButton = uibutton(app.PatientDataPanel, 'push');
            app.SavePatientButton.ButtonPushedFcn = createCallbackFcn(app, @SavePatientButtonPushed, true);
            app.SavePatientButton.Text = 'Guardar Paciente';
            app.IDEditFieldLabel = uilabel(app.PatientDataPanel);
            app.IDEditFieldLabel.Enable = 'off';
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.IDEditField.Enable = 'off';
            app.HeightEditFieldLabel = uilabel(app.PatientDataPanel);
            app.HeightEditFieldLabel.Text = 'Altura';
            app.HeightEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.WeightEditFieldLabel = uilabel(app.PatientDataPanel);
            app.WeightEditFieldLabel.Text = 'Peso';
            app.WeightEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.AgeEditFieldLabel = uilabel(app.PatientDataPanel);
            app.AgeEditFieldLabel.Text = 'Edad';
            app.AgeEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftWristElbowEditFieldLabel = uilabel(app.PatientDataPanel);
            app.LeftWristElbowEditFieldLabel.Text = 'Muñeca-Codo';
            app.LeftWristElbowEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftElbowShoulderEditFieldLabel = uilabel(app.PatientDataPanel);
            app.LeftElbowShoulderEditFieldLabel.Text = 'Codo-Hombro';
            app.LeftElbowShoulderEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftShoulderBreastboneEditFieldLabel = uilabel(app.PatientDataPanel);
            app.LeftShoulderBreastboneEditFieldLabel.Text = 'Hombro-Esternón';
            app.LeftShoulderBreastboneEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftWristHandleEditFieldLabel = uilabel(app.PatientDataPanel);
            app.LeftWristHandleEditFieldLabel.Text = 'Muñeca-Agarrador';
            app.LeftWristHandleEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightWristHandleEditFieldLabel = uilabel(app.PatientDataPanel);
            app.RightWristHandleEditFieldLabel.Text = 'Muñeca-Agarrador';
            app.RightWristHandleEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightWristElbowEditFieldLabel = uilabel(app.PatientDataPanel);
            app.RightWristElbowEditFieldLabel.Text = 'Muñeca-Codo';
            app.RightWristElbowEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightElbowShoulderEditFieldLabel = uilabel(app.PatientDataPanel);
            app.RightElbowShoulderEditFieldLabel.Text = 'Codo-Hombro';
            app.RightElbowShoulderEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightShoulderBreastboneEditFieldLabel = uilabel(app.PatientDataPanel);
            app.RightShoulderBreastboneEditFieldLabel.Text = 'Hombro-Esternón';
            app.RightShoulderBreastboneEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.GenderDropDownLabel = uilabel(app.PatientDataPanel);
            app.GenderDropDownLabel.Text = 'Género';
            app.GenderDropDown = uidropdown(app.PatientDataPanel);
            app.GenderDropDown.Items = {'Masculino', 'Femenino', ''};
            app.GenderDropDown.Value = 'Masculino';
            app.NameEditFieldLabel = uilabel(app.PatientDataPanel);
            app.NameEditFieldLabel.Text = 'Nombre';
            app.NameEditField = uieditfield(app.PatientDataPanel, 'text');
            app.BackToStartPatientDataButton = uibutton(app.PatientDataPanel, 'push');
            app.BackToStartPatientDataButton.ButtonPushedFcn = createCallbackFcn(app, @BackToStartPatientDataButtonPushed, true);
            app.BackToStartPatientDataButton.Text = 'Volver al Inicio';
            app.SurnamesEditFieldLabel = uilabel(app.PatientDataPanel);
            app.SurnamesEditFieldLabel.Text = 'Apellidos';
            app.SurnamesEditField = uieditfield(app.PatientDataPanel, 'text');
            app.SpasticityLevelEditFieldLabel = uilabel(app.PatientDataPanel);
            app.SpasticityLevelEditFieldLabel.Text = 'Nivel Diagnosticado Espasticidad';
            app.SpasticityLevelEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.BackToMenuPatientDataButton = uibutton(app.PatientDataPanel, 'push');
            app.BackToMenuPatientDataButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuPatientDataButtonPushed, true);
            app.BackToMenuPatientDataButton.Visible = 'off';
            app.BackToMenuPatientDataButton.Text = 'Volver al Menú';
            app.LoadPatientPanel = uipanel(app.UIFigure);
            app.LoadPatientPanel.Visible = 'off';
            app.LoadPatientTable = uitable(app.LoadPatientPanel);
            app.LoadPatientTable.ColumnName = {'Apellidos'; 'Nombre'; 'ID'};
            app.LoadPatientTable.RowName = {};
            app.LoadPatientTable.ColumnSortable = [true false true];
            app.LoadPatientTable.CellSelectionCallback = createCallbackFcn(app, @LoadPatientTableCellSelection, true);
            app.BackToStartLoadPatientButton = uibutton(app.LoadPatientPanel, 'push');
            app.BackToStartLoadPatientButton.ButtonPushedFcn = createCallbackFcn(app, @BackToStartLoadPatientButtonPushed, true);
            app.BackToStartLoadPatientButton.Text = 'Volver al Inicio';
            app.ChoosePatientButton = uibutton(app.LoadPatientPanel, 'push');
            app.ChoosePatientButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePatientButtonPushed, true);
            app.ChoosePatientButton.Text = 'Seleccionar Paciente';
            app.PatientMenuPanel = uipanel(app.UIFigure);
            app.PatientMenuPanel.Visible = 'off';
            app.EditPatientButton = uibutton(app.PatientMenuPanel, 'push');
            app.EditPatientButton.ButtonPushedFcn = createCallbackFcn(app, @EditPatientButtonPushed, true);
            app.EditPatientButton.Text = 'EDITAR DATOS DEL PACIENTE';
            app.StartTrialButton = uibutton(app.PatientMenuPanel, 'push');
            app.StartTrialButton.Text = 'COMENZAR PRUEBA';
            app.ShowMedicalHistoryButton = uibutton(app.PatientMenuPanel, 'push');
            app.ShowMedicalHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMedicalHistoryButtonPushed, true);
            app.ShowMedicalHistoryButton.Text = 'VER HISTORIAL DEL PACIENTE';
            app.PatientMenuEditFieldLabel = uilabel(app.PatientMenuPanel);
            app.PatientMenuEditFieldLabel.Text = 'PACIENTE';
            app.PatientMenuEditField = uieditfield(app.PatientMenuPanel, 'numeric');
            app.PatientMenuEditField.Editable = 'off';
            app.BackToStartPatientMenuButton = uibutton(app.PatientMenuPanel, 'push');
            app.BackToStartPatientMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BackToStartPatientMenuButtonPushed, true);
            app.BackToStartPatientMenuButton.Text = 'VOLVER AL INICIO';
            app.MedicalHistoryPanel = uipanel(app.UIFigure);
            app.MedicalHistoryPanel.Visible = 'off';
            app.MedicalHistoryTree = uitree(app.MedicalHistoryPanel);
            app.MedicalHistoryTree.Multiselect = 'on';
            app.MedicalHistoryTree.SelectionChangedFcn = createCallbackFcn(app, @MedicalHistoryTreeSelectionChanged, true);
            app.PersonalDataPanel = uipanel(app.MedicalHistoryPanel);
            app.PersonalDataPanel.Title = 'Datos Personales';
            app.PersonalDataPanel.Visible = 'off';
            app.LeftArmLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftArmLabel2.Text = 'Medidas Brazo Izquierdo';
            app.RightArmLabel2 = uilabel(app.PersonalDataPanel);
            app.RightArmLabel2.Text = 'Medidas Brazo Derecho';
            app.IDEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.IDEditFieldLabel2.Text = 'ID';
            app.IDEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.IDEditField2.Editable = 'off';
            app.SpasticityLevelEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.SpasticityLevelEditFieldLabel2.Text = 'Nivel Diagnosticado Espasticidad';
            app.SpasticityLevelEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.SpasticityLevelEditField2.Editable = 'off';
            app.HeightEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.HeightEditFieldLabel2.Text = 'Altura';
            app.HeightEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.HeightEditField2.Editable = 'off';
            app.WeightEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.WeightEditFieldLabel2.Text = 'Peso';
            app.WeightEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.WeightEditField2.Editable = 'off';
            app.AgeEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.AgeEditFieldLabel2.Text = 'Edad';
            app.AgeEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.AgeEditField2.Editable = 'off';
            app.LeftWristElbowEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftWristElbowEditFieldLabel2.Text = 'Muñeca-Codo';
            app.LeftWristElbowEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.LeftWristElbowEditField2.Editable = 'off';
            app.LeftElbowShoulderEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftElbowShoulderEditFieldLabel2.Text = 'Codo-Hombro';
            app.LeftElbowShoulderEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.LeftElbowShoulderEditField2.Editable = 'off';
            app.LeftShoulderBreastboneEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftShoulderBreastboneEditFieldLabel2.Text = 'Hombro-Esternón';
            app.LeftShoulderBreastboneEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.LeftShoulderBreastboneEditField2.Editable = 'off';
            app.LeftWristHandleEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftWristHandleEditFieldLabel2.Text = 'Muñeca-Agarrador';
            app.LeftWristHandleEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.LeftWristHandleEditField2.Editable = 'off';
            app.RightWristHandleEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.RightWristHandleEditFieldLabel2.Text = 'Muñeca-Agarrador';
            app.RightWristHandleEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.RightWristHandleEditField2.Editable = 'off';
            app.RightWristElbowEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.RightWristElbowEditFieldLabel2.Text = 'Muñeca-Codo';
            app.RightWristElbowEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.RightWristElbowEditField2.Editable = 'off';
            app.RightElbowShoulderEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.RightElbowShoulderEditFieldLabel2.Text = 'Codo-Hombro';
            app.RightElbowShoulderEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.RightElbowShoulderEditField2.Editable = 'off';
            app.RightShoulderBreastboneEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.RightShoulderBreastboneEditFieldLabel2.Text = 'Hombro-Esternón';
            app.RightShoulderBreastboneEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.RightShoulderBreastboneEditField2.Editable = 'off';
            app.NameEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.NameEditFieldLabel2.Text = 'Nombre';
            app.NameEditField2 = uieditfield(app.PersonalDataPanel, 'text');
            app.NameEditField2.Editable = 'off';
            app.SurnamesEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.SurnamesEditFieldLabel2.Text = 'Apellidos';
            app.SurnamesEditField2 = uieditfield(app.PersonalDataPanel, 'text');
            app.SurnamesEditField2.Editable = 'off';
            app.GenderEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.GenderEditFieldLabel2.Text = 'Género';
            app.GenderEditField2 = uieditfield(app.PersonalDataPanel, 'text');
            app.GenderEditField2.Editable = 'off';
            app.BackToMenuMedicalHistoryButton = uibutton(app.MedicalHistoryPanel, 'push');
            app.BackToMenuMedicalHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuMedicalHistoryButtonPushed, true);
            app.BackToMenuMedicalHistoryButton.Text = 'Volver al Menú';
            app.ResultsButtonsPanel = uipanel(app.MedicalHistoryPanel);
            app.ResultsButtonsPanel.Title = 'Seleccionar Resultados';
            app.TrajectoryLabel = uilabel(app.ResultsButtonsPanel);
            app.TrajectoryLabel.Text = 'Trayectoria';
            app.JointLabel = uilabel(app.ResultsButtonsPanel);
            app.JointLabel.Text = 'Articular';
            app.qButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.qButton.ButtonPushedFcn = createCallbackFcn(app, @qButtonPushed, true);
            app.qButton.Text = 'q';
            app.dqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.dqButton.ButtonPushedFcn = createCallbackFcn(app, @dqButtonPushed, true);
            app.dqButton.Text = 'dq';
            app.EqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EqButton.ButtonPushedFcn = createCallbackFcn(app, @EqButtonPushed, true);
            app.EqButton.Text = 'E(q)';
            app.EdqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EdqButton.ButtonPushedFcn = createCallbackFcn(app, @EdqButtonPushed, true);
            app.EdqButton.Text = 'E(dq)';
            app.CartesianLabel = uilabel(app.ResultsButtonsPanel);
            app.CartesianLabel.Text = 'Cartesiana';
            app.xButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.ExButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.ExButton.ButtonPushedFcn = createCallbackFcn(app, @ExButtonPushed, true);
            app.ExButton.Text = 'E(x)';
            app.EdxButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EdxButton.ButtonPushedFcn = createCallbackFcn(app, @EdxButtonPushed, true);
            app.EdxButton.Text = 'E(dx)';
            app.ThreeDButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.ThreeDButton.ButtonPushedFcn = createCallbackFcn(app, @ThreeDButtonPushed, true);
            app.ThreeDButton.Text = '3D';
            app.dxButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.dxButton.ButtonPushedFcn = createCallbackFcn(app, @dxButtonPushed, true);
            app.dxButton.Text = 'dx';
            app.ForceSensorLabel = uilabel(app.ResultsButtonsPanel);
            app.ForceSensorLabel.Text = 'Sensor Fuerza Par';
            app.FTButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.FTButton.ButtonPushedFcn = createCallbackFcn(app, @FTButtonPushed, true);
            app.FTButton.Text = 'FT';
            app.DelsysSensorsLabel = uilabel(app.ResultsButtonsPanel);
            app.DelsysSensorsLabel.Text = 'Sensores Delsys';
            app.EMGButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EMGButton.ButtonPushedFcn = createCallbackFcn(app, @EMGButtonPushed, true);
            app.EMGButton.Text = 'EMG';
            app.AccelerationsButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.AccelerationsButton.ButtonPushedFcn = createCallbackFcn(app, @AccelerationsButtonPushed, true);
            app.AccelerationsButton.Text = 'Aceleraciones';
            app.AngularVelocityButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.AngularVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @AngularVelocityButtonPushed, true);
            app.AngularVelocityButton.Text = 'Velocidad Angular';
            app.ddqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.ddqButton.ButtonPushedFcn = createCallbackFcn(app, @ddqButtonPushed, true);
            app.ddqButton.Text = 'ddq';
            app.EddqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EddqButton.ButtonPushedFcn = createCallbackFcn(app, @EddqButtonPushed, true);
            app.EddqButton.Text = 'E(ddq)';
            app.EpqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpqButton.ButtonPushedFcn = createCallbackFcn(app, @EpqButtonPushed, true);
            app.EpqButton.Text = 'Ep(q)';
            app.EpdqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpdqButton.ButtonPushedFcn = createCallbackFcn(app, @EpdqButtonPushed, true);
            app.EpdqButton.Text = 'Ep(dq)';
            app.EpddqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpddqButton.ButtonPushedFcn = createCallbackFcn(app, @EpddqButtonPushed, true);
            app.EpddqButton.Text = 'Ep(ddq)';
            app.EpxButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpxButton.ButtonPushedFcn = createCallbackFcn(app, @EpxButtonPushed, true);
            app.EpxButton.Text = 'Ep(x)';
            app.EpdxButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpdxButton.ButtonPushedFcn = createCallbackFcn(app, @EpdxButtonPushed, true);
            app.EpdxButton.Text = 'Ep(dx)';
            app.JointPrecisionLabel = uilabel(app.ResultsButtonsPanel);
            app.JointPrecisionLabel.Text = 'Precisión:';
            app.JointAccuracyLabel = uilabel(app.ResultsButtonsPanel);
            app.JointAccuracyLabel.Text = 'Exactitud:';
            app.CartesianPrecisionLabel = uilabel(app.ResultsButtonsPanel);
            app.CartesianPrecisionLabel.Text = 'Precisión:';
            app.CartesianAccuracyLabel = uilabel(app.ResultsButtonsPanel);
            app.CartesianAccuracyLabel.Text = 'Exactitud:';
            app.TrialDataPanel = uipanel(app.MedicalHistoryPanel);
            app.TrialDataPanel.Title = 'Datos Trayectoria Seleccionada';
            app.TrialDataPatientEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataPatientEditField.Editable = 'off';
            app.TrialDataChoosedArmEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataChoosedArmEditField.Editable = 'off';
            app.TrialDataTrajectoryDateCapturedEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataTrajectoryDateCapturedEditField.Editable = 'off';
            app.TrialDataTrajectoryDateExecutedEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataTrajectoryDateExecutedEditField.Editable = 'off';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Visible = 'off';
            app.CloseImage = uiimage(app.PlotsPanel);
            app.CloseImage.ImageClickedFcn = createCallbackFcn(app, @CloseImageClicked, true);
            app.CloseImage.ImageSource = 'close.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboespasDefinitivo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end